classdef appm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Image              matlab.ui.control.Image
        GridLayout2        matlab.ui.container.GridLayout
        GridLayout4        matlab.ui.container.GridLayout
        TIMPEditField      matlab.ui.control.NumericEditField
        TIMPLabel          matlab.ui.control.Label
        GridLayout3        matlab.ui.container.GridLayout
        SCOREditField      matlab.ui.control.NumericEditField
        SCORLabel          matlab.ui.control.Label
        RESTARTButton      matlab.ui.control.Button
        JOCCUTIMPButton    matlab.ui.control.Button
        dif                matlab.ui.control.DropDown
        TIMPINFINITButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        x
        ceas       timer
        timp
        grupbutoane matlab.ui.container.ButtonGroup
        scor=0
        player      audioplayer= audioplayer.empty;


    end
    methods (Access = public)


        function CreareButoane(app,~,n)
    

     %-----------------generare grup de butoane (doar spatiu, fara butoane)
           app.grupbutoane=uibuttongroup(app.GridLayout);
           app.grupbutoane.BackgroundColor= app.GridLayout2.BackgroundColor;
           app.grupbutoane.Layout.Row = 1;
           app.grupbutoane.Layout.Column = 2;
           latura=app.GridLayout.RowHeight{1}./n;


     %-----------------------generare perechi de numere si randomizare
           m=n*n/2;
           A=1:m;
            A=[A,A];


            idx=randperm(n*n);
            A=A(idx);

            A=reshape(A,n,n);
            
     %--------------------------------------incarcare melodii
            
            %melodii = {'nirvana.mp3', 'bad_romance.mp3', 'beegees.mp3', 'cantina.mp3', 'fur_elise.mp3', 'metallica.mp3', 'mjackson.mp3', 'vivaldi.mp3'};


            %amesteca si melodiile, fara asta, un joc cu 4x4 ar avea mereu
            %acelasi 8 melodii(primele)

            B=randperm(32);
            app.player=app.player(B);


     %--------------functie callback pt ca un buton sa dea play melodiei asociate
            %numarului sau
            
                persistent fors
                fors=1;
                %persitent -> pt ca valorile sa fie tinute minte dupa incheierea unui apel
                %fors -> first or second, daca varaibila este =1, butonul e primul,daca e =2, e al doilea
                
                persistent first;
                persistent second;
                persistent i1;
                persistent j1;

            function Audio(~,i,j)
                %if src.Value == 1 %daca butonul e apasat
                app.RESTARTButton.Enable='off';
                app.grupbutoane.Enable='off';
                    play(app.player(A(i,j)));
                
                    
                    %matrice(i,j).Enable='off'
                 
                %else
                    %stop(player(A(i,j)));

           
                %end


             %if src.Value == 0
                

                if fors==1
                    fors=2;
                    first=A(i,j);
                    i1=i;
                    j1=j;
                    %matrice(i,j).BackgroundColor = '#7f1734';
                    matrice(i,j).Enable='off';


                 else
                    fors=1;
                    second=A(i,j);
                   %matrice(i1,j1).Enable='off';
                    if i1~=i || j1~=j

                      if second==first
                        matrice(i,j).Visible='off';
                        matrice(i1,j1).Visible='off';
                        app.scor=app.scor+1;
                        app.SCOREditField.Value=app.scor;
                      else
                        
                        %matrice(i,j).BackgroundColor = '#7f1734';
                        %matrice(i,j).Value=0;
                        
                 
                      end
                     
                    end
                        
                end


                pause(3.1);
                app.grupbutoane.Enable='on';
                app.RESTARTButton.Enable='on';
                if fors==1
                 
                    
                    matrice(i1,j1).Enable='on';
                    
                 
                end
                
                
             %end
                
               
            end


        %------------------------------generare butoane
        matrice=gobjects(n,n);
        spatiu=(app.GridLayout.ColumnWidth{2} - app.GridLayout.RowHeight{1})./2;
            for i = 1:n
               for j = 1:n
                    matrice(i,j)=uicontrol(app.grupbutoane,'String','🎵','FontSize',22,'Style','togglebutton','BackgroundColor','#c15b4e' , 'Position', [ latura*(i-1)+spatiu latura*(j-1) latura latura] , 'Callback', @(src,event) Audio(src,i,j));       
                    %, @(src,event) alegere(src,i,j)
               end
            end


        end


        function updateTimer(app)
            
            app.timp = app.timp - 1;
            app.TIMPEditField.Value=app.timp;

            % După 10 secunde, oprește timer-ul.
            if app.timp == 0
                app.Image.ImageSource=imread('sunete\ghinion.jpg');
                stop(app.ceas);
                app.grupbutoane.Visible="off";


            end

            if app.scor==(app.x.^2)/2
                app.Image.ImageSource=imread('sunete\felicitari.jpg');
                stop(app.ceas);
                app.grupbutoane.Visible="off";


            end
        end
        
        function updateTimer2(app)
            app.timp = app.timp + 1;
            app.TIMPEditField.Value=app.timp;

            if app.scor==(app.x.^2)/2
                app.Image.ImageSource=imread('sunete\felicitari.jpg');
                stop(app.ceas);
                app.grupbutoane.Visible="off";
                
            
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         %melodii=strings(32);
         for i = 1:32
                melodii{i}= num2str(i) + ".mp3";
         end


         pathname='sunete';

         for i = 1:32
                
                filename = melodii{i};
                fullfile_path = fullfile(pathname, filename);
                [y, fs] = audioread(fullfile_path);
                app.player(i) = audioplayer(y, fs);
         end


        end

        % Value changed function: dif
        function NumarliniicoloaneDropDownValueChanged(app, event)
            dific = app.dif.Value;
            
            if strcmp(dific,"USOR  ") == 1
                value=2;
            elseif strcmp(dific,"MEDIU ") == 1
                value=4;
            elseif strcmp(dific,"GREU  ") == 1
                value=6;
            elseif strcmp(dific,"EXTREM") == 1
                value=8;
            else 
                value=0;
            end

            
            app.x=value;

            app.JOCCUTIMPButton.Enable='on';
            app.TIMPINFINITButton.Enable='on';

            %CreareButoane(app,event,value);
        end

        % Button pushed function: JOCCUTIMPButton
        function JOCCUTIMPButtonPushed(app, event)
            app.TIMPINFINITButton.Enable='off';
            app.JOCCUTIMPButton.Enable='off';
            app.dif.Enable='off';
            app.RESTARTButton.Enable='on';
                  
            
            CreareButoane(app,event,app.x);
            if app.x==2
                app.timp = 20;
            elseif app.x==4
                app.timp=120;
            elseif app.x==6
                app.timp=300;
            else 
                app.timp=600;
            end
            app.ceas = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(src, event) updateTimer(app));
            app.TIMPEditField.Value=app.timp;
            start(app.ceas);


        end

        % Button pushed function: TIMPINFINITButton
        function TIMPINFINITButtonPushed(app, event)
            app.JOCCUTIMPButton.Enable='off';
            app.TIMPINFINITButton.Enable='off';
            app.dif.Enable='off';
            app.RESTARTButton.Enable='on';
            
            CreareButoane(app,event,app.x);
            app.timp=0;
            app.ceas = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(src, event) updateTimer2(app));
            app.TIMPEditField.Value=app.timp;
            start(app.ceas);
        end

        % Button pushed function: RESTARTButton
        function RESTARTButtonPushed(app, event)
            app.JOCCUTIMPButton.Enable='on';
            app.TIMPINFINITButton.Enable='on';
            app.dif.Enable='on';
            app.RESTARTButton.Enable='off';
            app.scor=0;
            app.SCOREditField.Value=app.scor;
            stop(app.ceas);
            app.timp=0;
            app.TIMPEditField.Value=app.timp;
                  app.Image.ImageSource=imread('sunete\pozax.png');
            app.grupbutoane.Visible='off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.TIMPINFINITButton = uibutton(app.GridLayout2, 'push');
            app.TIMPINFINITButton.ButtonPushedFcn = createCallbackFcn(app, @TIMPINFINITButtonPushed, true);
            app.TIMPINFINITButton.Enable = 'off';
            app.TIMPINFINITButton.Text = 'TIMP INFINIT ♾ ';
            app.dif = uidropdown(app.GridLayout2);
            app.dif.Items = {'NIVEL  ', 'USOR  ', 'MEDIU ', 'GREU  ', 'EXTREM'};
            app.dif.ValueChangedFcn = createCallbackFcn(app, @NumarliniicoloaneDropDownValueChanged, true);
            app.dif.Value = 'NIVEL  ';
            app.JOCCUTIMPButton = uibutton(app.GridLayout2, 'push');
            app.JOCCUTIMPButton.ButtonPushedFcn = createCallbackFcn(app, @JOCCUTIMPButtonPushed, true);
            app.JOCCUTIMPButton.Enable = 'off';
            app.JOCCUTIMPButton.Text = 'JOC CU TIMP ⏱️';
            app.RESTARTButton = uibutton(app.GridLayout2, 'push');
            app.RESTARTButton.ButtonPushedFcn = createCallbackFcn(app, @RESTARTButtonPushed, true);
            app.RESTARTButton.Enable = 'off';
            app.RESTARTButton.Text = 'RESTART';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.SCORLabel = uilabel(app.GridLayout3);
            app.SCORLabel.Text = 'SCOR:';
            app.SCOREditField = uieditfield(app.GridLayout3, 'numeric');
            app.SCOREditField.Editable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.TIMPLabel = uilabel(app.GridLayout4);
            app.TIMPLabel.Text = 'TIMP:';
            app.TIMPEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TIMPEditField.Editable = 'off';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'sunete', 'pozax.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end